classdef signaltsmarker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalTimestampMarkerappUIFigure  matlab.ui.Figure
        ygridEditField             matlab.ui.control.NumericEditField
        ygridCheckBox              matlab.ui.control.CheckBox
        GridLabel                  matlab.ui.control.Label
        xgridEditField             matlab.ui.control.NumericEditField
        xgridCheckBox              matlab.ui.control.CheckBox
        ConvsimtrainButton         matlab.ui.control.Button
        durtrainEditField          matlab.ui.control.NumericEditField
        durtrainEditFieldLabel     matlab.ui.control.Label
        freqtrainEditField         matlab.ui.control.NumericEditField
        freqtrainEditFieldLabel    matlab.ui.control.Label
        ViewwholesignalButton      matlab.ui.control.Button
        ClearpointsButton          matlab.ui.control.Button
        MarkersCheckBox            matlab.ui.control.CheckBox
        EndButton                  matlab.ui.control.Button
        ClearmarkerButton          matlab.ui.control.Button
        TimefreqdecompLabel        matlab.ui.control.Label
        EstimatesLabel             matlab.ui.control.Label
        SegmentLabel               matlab.ui.control.Label
        TimefreqCheckBox           matlab.ui.control.CheckBox
        SegestimatesCheckBox       matlab.ui.control.CheckBox
        RecalculateplotButton      matlab.ui.control.Button
        TimestampLabel             matlab.ui.control.Label
        freqlim2EditField          matlab.ui.control.NumericEditField
        freqlim1EditField          matlab.ui.control.NumericEditField
        freqlimEditFieldLabel      matlab.ui.control.Label
        EstimateDropDown           matlab.ui.control.DropDown
        EstimateDropDownLabel      matlab.ui.control.Label
        AbsoluteButton             matlab.ui.control.Button
        OriginalButton             matlab.ui.control.Button
        notchlowcutEditField       matlab.ui.control.NumericEditField
        lowcutEditField_2Label     matlab.ui.control.Label
        notchhighcutEditField      matlab.ui.control.NumericEditField
        highcutEditField_2Label    matlab.ui.control.Label
        NotchButton                matlab.ui.control.Button
        filterlowcutEditField      matlab.ui.control.NumericEditField
        lowcutEditFieldLabel       matlab.ui.control.Label
        filterhighcutEditField     matlab.ui.control.NumericEditField
        highcutEditFieldLabel      matlab.ui.control.Label
        FilterButton               matlab.ui.control.Button
        MethodTFDropDown           matlab.ui.control.DropDown
        MethodDropDownLabel        matlab.ui.control.Label
        noverlapEditField          matlab.ui.control.NumericEditField
        noverlapEditFieldLabel     matlab.ui.control.Label
        LengthEditField            matlab.ui.control.NumericEditField
        LengthEditFieldLabel       matlab.ui.control.Label
        GetpointsButton            matlab.ui.control.Button
        MarkerlabelEditField       matlab.ui.control.EditField
        MarkerlabelEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        figX
        axX % Description
        sbp
        markerplot
        
        X
        Xorg
        Fs
        t
        tlim
        
        L
        noverlap
        Xseg
        tseg
        
        estfun
        Xsegest
        
        tffun
        tf
        f
        
        Markers
        markerlabel
        idx_label
        idx_marker
        ts_marker
        
    end
    
    methods (Access = public)
        
        %Plot signal
        function [] = plotSignal(app)
            disp('Plotting signal...')
            
            app.sbp=[];
            app.tlim = get(gca,'xlim');
            
            Nsbp=1;
            if app.SegestimatesCheckBox.Value == true
                Nsbp=Nsbp+1;
            end
            if app.TimefreqCheckBox.Value == true
                Nsbp=Nsbp+1;
            end
            
            isbp=1;
            %Plot signal
            subplot(Nsbp,1,1); app.sbp(isbp)=gca;
            plot(app.t,app.X,'k');
            title('Signal')
            ylabel('Amp.')
            xlabel('Time (s)')
            isbp=isbp+1;
            
            %Plot markers
            plotMarkers(app)

            %Plot markers
            plotGrid(app)
            
            %Plot segment estimates
            if app.SegestimatesCheckBox.Value == true
                subplot(Nsbp,1,isbp); app.sbp(isbp)=gca;
                plot(app.tseg,app.Xsegest,'k');
                ylabel(app.EstimateDropDown.Value)
                xlabel('Time (s)')
                isbp=isbp+1;
            end
        
            %Plot spectrogram
            if app.TimefreqCheckBox.Value == true
                subplot(Nsbp,1,isbp); app.sbp(isbp)=gca;
                imagesc(app.tseg,app.f,app.tf);
                axis xy
                colormap jet
                caxis([0 10^-5*(max(max(app.tf)))])
                ylim([app.freqlim1EditField.Value app.freqlim2EditField.Value]);
                ylabel('f. (Hz).')
                xlabel('Time (s)')
                isbp=isbp+1;
            end
            
            linkaxes(app.sbp,'x')
            xlim([app.tlim])
            
            disp('Plotted signal')
            assignin("base",'signalmarkers',app.Markers)
        end
        
        %Plot markers
        function [] = plotMarkers(app)
            axes(app.sbp(1)); 
            ax = gca;
            delete(ax.Children(1:end-1)) %delete all except first plot
            
            markercolor=colormap(app.sbp(1),'prism');
            hold on
            if app.MarkersCheckBox.Value
                for imarker = 1:size(app.Markers,2)
                    app.markerplot(imarker)=scatter(app.t(app.Markers{2,imarker}),app.X(app.Markers{2,imarker}),...
                        's','filled','markerfacecolor',markercolor(imarker,:),'sizedata',100);
                end
            hold off
            end
            assignin("base",'signalmarkers',app.Markers)
            
            plotGrid(app)
        end
        
        %Plot grid lines
        function [] = plotGrid(app)
            axes(app.sbp(1)); 
%             ax = gca;
%             delete(ax.Children(1:end-1)) %delete all except first plot
                        
            if app.xgridCheckBox.Value == true
                YLim = get(gca,'YLim');
                hold on,plot([app.xgridEditField.Value app.xgridEditField.Value],YLim,'k--')
            end
           
            if app.ygridCheckBox.Value == true
                XLim = get(gca,'XLim');
                hold on,plot(XLim,[app.ygridEditField.Value app.ygridEditField.Value],'k--')
            end
        end 
       
        function [] = RecalculatePlot(app)
            disp('Recalculating plot...')
            %Segmentation
            app.L = app.LengthEditField.Value;
            app.noverlap = app.noverlapEditField.Value;
            [app.Xseg,app.tseg] = segmentation(app,app.X,app.L,app.noverlap);
                app.tseg = app.tseg/app.Fs;
            
            %Estimates
            switch app.EstimateDropDown.Value
                case 'Energy'
                    estfun = @(x) sum(x.^2);
                case 'RMS'
                    estfun = @(x) rms(x);
                case 'Max abs.'
                    estfun = @(x) max(abs(x));
                case 'Mean'
                    estfun = @(x) mean(x);
                case 'Zero-cross'
                    estfun = @(x) sum(x.*circshift(x,-1,2) <= 0);
                case 'Band power'
                    disp('Under development')
                    return

            end
            app.Xsegest = cellfun(@(x) estfun(x),app.Xseg);
            
            %Time-freq.
            switch app.MethodTFDropDown.Value
                case 'FFT'
%                     tffun = @(x) shiftdim(fft(x).*conj(fft(x)));
                    return
                case 'psd'
                    tffun = @(x) periodogram(x,hamming(length(x)),[],app.Fs);
                    
            end
            [tf,f] = cellfun(@(x) tffun(x),app.Xseg,'un',0);
            app.tf = cell2mat(tf);
            app.f = f{1};
            
            disp('Recalculated plot')
        end
    
    end
    
    methods (Access = private)
        
        function [Xseg,tseg] = segmentation(app,X,L,noverlap)
        
        if nargin<4
            noverlap = 0;
        end
        
        %Sizes
        channels = size(X,3);
        trials = size(X,1);
        N = size(X,2);
        
        t = 1:N;
        
        % if noverlap is proportion or zero
        if noverlap<1
            noverlap = noverlap*L;
        end
        
        %Number of segments considering overlap
        Nseg = (N-L)/(L-noverlap)+1; %OBS: excludes final segment if does not complete full seg_length
        
        for channel = 1:channels
            for trial = 1:trials
                for iseg = 1:Nseg
                    inicio = (iseg-1)*(L-noverlap)+1;
                    fim = inicio+L-1;
        
                    Xseg{trial,iseg,channel} = X(trial,inicio:fim);
                    tseg(trial,iseg) = mean(t(inicio:fim));
                end
            end
        end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, X, Fs)
            app.SignalTimestampMarkerappUIFigure.Name = 'Signal Timestamp Marker app';
            movegui(app.SignalTimestampMarkerappUIFigure,'onscreen')
            
            app.figX = figure; movegui('onscreen')
            app.axX = gca;
            
            app.Xorg = shiftdim(X)';
            app.X = app.Xorg;
            if nargin==3
                app.Fs = Fs;
            else
                app.Fs = 1;
            end
            app.t = linspace(0,numel(X)/app.Fs,numel(X));
            
            %Initialize Markers
            app.Markers{1,1} = app.MarkerlabelEditField.Value;
            app.Markers{2,1} = [];
            app.markerlabel = app.MarkerlabelEditField.Value;
            app.idx_label = 1;
            
            %Initialize "signalmarkers" to workspace
            assignin("base",'signalmarkers',app.Markers)
            
            %Plot
%             app.tlim = [0 app.t(end)];
            RecalculatePlot(app)
            plotSignal(app)
            xlim("auto")      
            
            %Display instructions
            disp(' ')
            disp('1) Define marker label (defaut: "idx_marker")')
            disp('2) Zoom to clear locations. OBS: do not zoom after pushing buttons in the app')
            disp('3) Press "Get points", click at local locations and press Enter')
            disp('4) Repeat for other markers')
            disp('5) Press "Clear points" and click close to points for exclusion and press Enter')
            disp('6) Press "Clear marker" to exclude the whole marker and timestamps')
            disp('7) Check indices in real time by opening "signalmarkers" at the end or while locatinig timestamps on signal')
            disp(' ')

        end

        % Button pushed function: RecalculateplotButton
        function RecalculateplotButtonPushed(app, event)
            RecalculatePlot(app)
            plotSignal(app)
        end

        % Value changed function: MarkersCheckBox
        function MarkersCheckBoxValueChanged(app, event)
            plotMarkers(app)            
        end

        % Value changed function: SegestimatesCheckBox
        function SegestimatesCheckBoxValueChanged(app, event)
            RecalculatePlot(app)
            plotSignal(app)            
        end

        % Value changed function: TimefreqCheckBox
        function TimefreqCheckBoxValueChanged(app, event)
            RecalculatePlot(app)
            plotSignal(app)            
        end

        % Button pushed function: ViewwholesignalButton
        function ViewwholesignalButtonPushed(app, event)
            xlim(app.sbp(1),'auto')
            ylim(app.sbp(1),'auto')
            app.tlim = [get(gca,'xlim')];
            plotSignal(app)            
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            app.X = app.Xorg;
            
            RecalculatePlot(app)
            plotSignal(app)
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            disp('Filtering signal...')
            locutoff = app.filterlowcutEditField.Value;
            hicutoff = app.filterhighcutEditField.Value;
            
            if locutoff %if non zero
                app.X = eegfilt(app.X,app.Fs,locutoff,0,length(app.X),[],0,'fir1');
            end
            if hicutoff
                app.X = eegfilt(app.X,app.Fs,0,hicutoff,length(app.X),[],0,'fir1');
            end
            disp('Filtered signal')
            
            RecalculatePlot(app)
            plotSignal(app)

        end

        % Button pushed function: NotchButton
        function NotchButtonPushed(app, event)
            disp('Notch-filtering signal...')
            notchhicut = app.notchhighcutEditField.Value;
            notchlocut = app.notchlowcutEditField.Value;
            
            Xnotchhicut = eegfilt(app.X,app.Fs,notchlocut,[],length(app.X),[],0,'fir1');
            Xnotchlocut = eegfilt(app.X,app.Fs,[],notchhicut,length(app.X),[],0,'fir1');
            app.X = Xnotchhicut+Xnotchlocut;
            disp('Notch-filtered signal')
            
            RecalculatePlot(app)
            plotSignal(app)
            
        end

        % Callback function
        function ConvsimtrainButtonPushed(app, event)
            disp('Applying convolution to simulated train')

            ftrain = app.freqtrainEditField.Value;
            durtrain = app.durtrainEditField.Value*app.Fs;
            
            simtrain = zeros(durtrain,1);
            simtrain(1:(Fs/ftrain):end) = 1;
            
            app.X = conv(app.X,simtrain,'same');            

            RecalculatePlot(app)
            plotSignal(app)
            
        end

        % Button pushed function: AbsoluteButton
        function AbsoluteButtonPushed(app, event)
            app.X = abs(app.X);            
            
            RecalculatePlot(app)
            plotSignal(app)
        end

        % Value changed function: MarkerlabelEditField
        function MarkerlabelEditFieldValueChanged(app, event)
            app.markerlabel = app.MarkerlabelEditField.Value;
            
            app.idx_label = find(cellfun(@(x) strcmp(x,app.markerlabel),app.Markers(1,:)));
            if app.idx_label %label already exists
                app.idx_marker = app.Markers{2,app.idx_label};
            else %new label
                app.idx_label = size(app.Markers,2)+1;
                app.idx_marker = [];
                
                app.Markers{1,app.idx_label} = app.markerlabel;
                app.Markers{2,app.idx_label} = app.idx_marker;
            end
            
            %opção start-stop? ou dropdown(Event,Start-Stop)

        end

        % Button pushed function: GetpointsButton
        function GetpointsButtonPushed(app, event)
            MarkerlabelEditFieldValueChanged(app, event)
            
            disp('Click at points on signal and press enter')
            [xi,yi] = getpts(app.sbp(1));
%                 ux=mean(app.t); stdx = std(app.t);
%                 uy=mean(app.X); stdy = std(app.X);
%                 zx = normalize(app.t','zscore');
%                 zy = normalize(app.X','zscore');
            
            Npts = numel(xi);
            for ipt = 1:Npts
            [~,idx_marker(ipt,1)] = min(abs(xi(ipt)-app.t));
%             [~,idx_marker(ipt,1)] = min(pdist2([xi(ipt) yi(ipt)],[app.t' app.X']));
%             [~,idx_marker(ipt,1)] = min(pdist2([(xi(ipt)-ux)/stdx (yi(ipt)-uy)/stdy], ...
%                                                 [(app.t'-ux)/stdx (app.X'-uy)/stdy]));
            end
            
            if isempty(app.idx_marker)
                app.idx_marker = idx_marker; %new if empty
            else
                app.idx_marker = [app.idx_marker ; idx_marker]; %append if exist
            end
            
            app.idx_marker = unique(app.idx_marker); %in case are repeated    %sort?
            app.ts_marker = app.t(app.idx_marker)';
            
            app.Markers{2,app.idx_label} = app.idx_marker;
            app.Markers{3,app.idx_label} = app.ts_marker;
            
%             assignin("base",app.markerlabel,app.idx_marker)
%             assignin("base",[app.markerlabel '_ts'],app.ts_marker)   
            assignin("base",'signalmarkers',app.Markers)
            plotMarkers(app)

        end

        % Button pushed function: ClearmarkerButton
        function ClearmarkerButtonPushed(app, event)
            app.Markers(:,app.idx_label) = [];
            app.idx_label=[]; %so does not clear else
            
            if size(app.Markers,2)==0
                MarkerlabelEditFieldValueChanged(app, event)
            end
            
            assignin("base",'signalmarkers',app.Markers)
            plotMarkers(app)

        end

        % Button pushed function: ClearpointsButton
        function ClearpointsButtonPushed(app, event)
            disp('Click close to points you want to exclude and press Enter')
            [xi,yi] = getpts(app.sbp(1));
                        
            %Identify closest points to exclude
            Npts = numel(xi);
            if Npts %if any
                for ipt = 1:Npts
                [~,idx_marker_excl(ipt,1)] = min(abs(xi(ipt)-app.t(app.Markers{2,app.idx_label}))); %closest
                end
            else
                idx_marker_excl = numel(app.Markers{2,app.idx_label}); %last
            end
            
            %Exclude
            if ~isempty(app.Markers{2,app.idx_label})
                app.Markers{2,app.idx_label}(idx_marker_excl) = [];
                app.Markers{3,app.idx_label}(idx_marker_excl) = [];
            end
            
            if size(app.Markers,2)==0
                MarkerlabelEditFieldValueChanged(app, event)
            end
            
            assignin("base",'signalmarkers',app.Markers)
            plotMarkers(app)
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            assignin("caller",'signalmarkers',app.Markers)
            assignin("base",'signalmarkers',app.Markers)
            disp('Check variable signalmarkers')
            
            close(gcbf)
        end

        % Button pushed function: ConvsimtrainButton
        function ConvsimtrainButtonPushed2(app, event)
            disp('Applying convolution to simulated train')

            ftrain = app.freqtrainEditField.Value;
            durtrain = app.durtrainEditField.Value*app.Fs;
            
            simtrain = zeros(durtrain,1);
            simtrain(1:(app.Fs/ftrain):end) = 1;
            
            app.X = conv(app.X,simtrain,'same');            

            RecalculatePlot(app)
            plotSignal(app)
        end

        % Value changed function: xgridCheckBox
        function xgridCheckBoxValueChanged(app, event)
            plotMarkers(app)
        end

        % Value changed function: ygridCheckBox
        function ygridCheckBoxValueChanged(app, event)
            plotMarkers(app)
        end

        % Value changed function: xgridEditField
        function xgridEditFieldValueChanged(app, event)
            plotMarkers(app)
        end

        % Value changed function: ygridEditField
        function ygridEditFieldValueChanged(app, event)
            plotMarkers(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalTimestampMarkerappUIFigure = uifigure('Visible', 'off');
            app.SignalTimestampMarkerappUIFigure.Name = 'Signal Timestamp Marker app';
            app.MarkerlabelEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.MarkerlabelEditFieldLabel.Text = 'Marker label';
            app.MarkerlabelEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'text');
            app.MarkerlabelEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerlabelEditFieldValueChanged, true);
            app.MarkerlabelEditField.Tooltip = {'Define marker label. Type name in text box'};
            app.MarkerlabelEditField.Value = 'idx_marker';
            app.GetpointsButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.GetpointsButton.ButtonPushedFcn = createCallbackFcn(app, @GetpointsButtonPushed, true);
            app.GetpointsButton.Tooltip = {'1) Define marker label'; '2) Zoom to clear locations, press "Get points", click at local locations and press Enter, then locate other timestamps'; '3) Press "Get points", click at local locations and press Enter'; '4) Repeat for other markers'; '5) Check indices in real time by opening "signalmarkers" at the end or while locatinig timestamps on signal'};
            app.GetpointsButton.Text = 'Get points';
            app.LengthEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.LengthEditField.Value = 1000;
            app.noverlapEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.noverlapEditFieldLabel.Text = 'noverlap';
            app.noverlapEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.MethodDropDownLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodTFDropDown = uidropdown(app.SignalTimestampMarkerappUIFigure);
            app.MethodTFDropDown.Items = {'FFT', 'psd', 'multitaper'};
            app.MethodTFDropDown.Value = 'psd';
            app.FilterButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Tooltip = {'Bandpass filtered signal'};
            app.FilterButton.Text = 'Filter';
            app.highcutEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.highcutEditFieldLabel.Text = 'highcut';
            app.filterhighcutEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.filterhighcutEditField.Tooltip = {'Set to 0 to not perform filtering'};
            app.filterhighcutEditField.Value = 250;
            app.lowcutEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.lowcutEditFieldLabel.Text = 'lowcut';
            app.filterlowcutEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.filterlowcutEditField.Tooltip = {'Set to 0 to not perform filtering'};
            app.filterlowcutEditField.Value = 0.5;
            app.NotchButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.NotchButton.ButtonPushedFcn = createCallbackFcn(app, @NotchButtonPushed, true);
            app.NotchButton.Tooltip = {'Apply bandcut notch filtering'};
            app.NotchButton.Text = 'Notch';
            app.highcutEditField_2Label = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.highcutEditField_2Label.Text = 'highcut';
            app.notchhighcutEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.notchhighcutEditField.Value = 55;
            app.lowcutEditField_2Label = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.lowcutEditField_2Label.Text = 'lowcut';
            app.notchlowcutEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.notchlowcutEditField.Value = 65;
            app.OriginalButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Tooltip = {'Plot original signal'};
            app.OriginalButton.Text = 'Original';
            app.AbsoluteButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.AbsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.AbsoluteButton.Tooltip = {'Plot modulus of signal'};
            app.AbsoluteButton.Text = 'Absolute';
            app.EstimateDropDownLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.EstimateDropDownLabel.Text = 'Estimate';
            app.EstimateDropDown = uidropdown(app.SignalTimestampMarkerappUIFigure);
            app.EstimateDropDown.Items = {'Energy', 'RMS', 'Max abs.', 'Mean', 'Zero-cross', 'Band power'};
            app.EstimateDropDown.Value = 'Energy';
            app.freqlimEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.freqlimEditFieldLabel.Text = 'freq. lim.';
            app.freqlim1EditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.freqlim2EditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.freqlim2EditField.Value = 100;
            app.TimestampLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.TimestampLabel.Text = 'Timestamp';
            app.RecalculateplotButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.RecalculateplotButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateplotButtonPushed, true);
            app.RecalculateplotButton.Text = 'Recalculate plot';
            app.SegestimatesCheckBox = uicheckbox(app.SignalTimestampMarkerappUIFigure);
            app.SegestimatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegestimatesCheckBoxValueChanged, true);
            app.SegestimatesCheckBox.Text = 'Seg. estimates';
            app.TimefreqCheckBox = uicheckbox(app.SignalTimestampMarkerappUIFigure);
            app.TimefreqCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimefreqCheckBoxValueChanged, true);
            app.TimefreqCheckBox.Text = 'Time-freq.';
            app.SegmentLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.SegmentLabel.Text = 'Segment';
            app.EstimatesLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.EstimatesLabel.Text = 'Estimates';
            app.TimefreqdecompLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.TimefreqdecompLabel.Text = 'Time-freq. decomp.';
            app.ClearmarkerButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.ClearmarkerButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmarkerButtonPushed, true);
            app.ClearmarkerButton.Tooltip = {'Clear whole marker and timestamps'};
            app.ClearmarkerButton.Text = 'Clear marker';
            app.EndButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Tooltip = {'Close app'};
            app.EndButton.Text = 'End';
            app.MarkersCheckBox = uicheckbox(app.SignalTimestampMarkerappUIFigure);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.MarkersCheckBox.Text = 'Markers';
            app.MarkersCheckBox.Value = true;
            app.ClearpointsButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.ClearpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpointsButtonPushed, true);
            app.ClearpointsButton.Tooltip = {'Clear timestamp. Click close to specified marker for exclusion and press Enter'};
            app.ClearpointsButton.Text = 'Clear points';
            app.ViewwholesignalButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.ViewwholesignalButton.ButtonPushedFcn = createCallbackFcn(app, @ViewwholesignalButtonPushed, true);
            app.ViewwholesignalButton.Tooltip = {'View whole signal'};
            app.ViewwholesignalButton.Text = 'View whole signal';
            app.freqtrainEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.freqtrainEditFieldLabel.Text = 'freq. train';
            app.freqtrainEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.freqtrainEditField.Tooltip = {'Frequency of train'};
            app.freqtrainEditField.Value = 50;
            app.durtrainEditFieldLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.durtrainEditFieldLabel.Text = 'dur. train';
            app.durtrainEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.durtrainEditField.Tooltip = {'Duration of train (s)'};
            app.durtrainEditField.Value = 10;
            app.ConvsimtrainButton = uibutton(app.SignalTimestampMarkerappUIFigure, 'push');
            app.ConvsimtrainButton.ButtonPushedFcn = createCallbackFcn(app, @ConvsimtrainButtonPushed2, true);
            app.ConvsimtrainButton.Tooltip = {'Convolution with simulated train'};
            app.ConvsimtrainButton.Text = 'Conv. sim. train';
            app.xgridCheckBox = uicheckbox(app.SignalTimestampMarkerappUIFigure);
            app.xgridCheckBox.ValueChangedFcn = createCallbackFcn(app, @xgridCheckBoxValueChanged, true);
            app.xgridCheckBox.Text = 'x:';
            app.xgridEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.xgridEditField.ValueChangedFcn = createCallbackFcn(app, @xgridEditFieldValueChanged, true);
            app.GridLabel = uilabel(app.SignalTimestampMarkerappUIFigure);
            app.GridLabel.Text = 'Grid';
            app.ygridCheckBox = uicheckbox(app.SignalTimestampMarkerappUIFigure);
            app.ygridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ygridCheckBoxValueChanged, true);
            app.ygridCheckBox.Text = 'y:';
            app.ygridEditField = uieditfield(app.SignalTimestampMarkerappUIFigure, 'numeric');
            app.ygridEditField.ValueChangedFcn = createCallbackFcn(app, @ygridEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.SignalTimestampMarkerappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signaltsmarker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalTimestampMarkerappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalTimestampMarkerappUIFigure)
        end
    end
end